classdef spiderbotApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SIMULACINSPIDERBOTPanel  matlab.ui.container.Panel
        grafico3D                matlab.ui.control.UIAxes
        foto                     matlab.ui.control.Image
        q1SliderLabel            matlab.ui.control.Label
        q1Slider                 matlab.ui.control.Slider
        q2SliderLabel            matlab.ui.control.Label
        q2Slider                 matlab.ui.control.Slider
        q3SliderLabel            matlab.ui.control.Label
        q3Slider                 matlab.ui.control.Slider
        L1EditFieldLabel         matlab.ui.control.Label
        L1EditField              matlab.ui.control.NumericEditField
        L2EditFieldLabel         matlab.ui.control.Label
        L2EditField              matlab.ui.control.NumericEditField
        L3EditFieldLabel         matlab.ui.control.Label
        L3EditField              matlab.ui.control.NumericEditField
        cmLabel                  matlab.ui.control.Label
        cmLabel_2                matlab.ui.control.Label
        cmLabel_3                matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global vista1
            global vista2
           
            %COLOCAR IMÁGEN DEL SPIDER BOT REFERENCIANDO LAS VARIABLES
            [ruta, nombre, extension] = fileparts(mfilename('fullpath'));
            rutaCompleta = strcat(ruta,'\imagenes\pataSpiderBot.png');
            app.foto.ImageSource = rutaCompleta;
            
            %OBTENER EL TAMAÑO DE LOS ESLABONES DEL SPIDERBOT Y LOS ÁNGULOS ACTUALES
            L1 = app.L1EditField.Value;
            L2 = app.L2EditField.Value;
            L3 = app.L3EditField.Value;
            q1 = app.q1Slider.Value;
            q2 = app.q2Slider.Value;
            q3 = app.q3Slider.Value;
            
            %POSICIONES DE LAS ARTICULACIONES EN FUNCIÓN DEL TAMAÑO DE ESLABONES Y ÁNGULOS DEL MISMO
            L1x = L1*sind(q1);
            L2x = L1x+L2*cosd(q2)*sind(q1);
            L3x = L2x-L3*sind(q3)*sind(q1);
            
            L1y = 0;
            L2y = -L2*sind(q2);
            L3y = L2y - L3*cosd(q3);
            
            L1z = L1*cosd(q1);
            L2z = L1z + L2*cosd(q2)*cosd(q1);
            L3z = L2z - L3*sind(q3)*cosd(q1);
            
            %SE CREAN VECTORES QUE GUARDEN LAS PISICIONES, PARA POSTERIORMENTE GRAFICARLOS
            x = [0, L1x, L2x, L3x];
            y = [0, L1y, L2y, L3y];
            z = [0, L1z, L2z, L3z];
            
            %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
            app.grafico3D.NextPlot = 'replace';
            
            plot3(app.grafico3D,x,-z,y);
            app.grafico3D.XGrid = 1;
            app.grafico3D.YGrid = 1;
            app.grafico3D.ZGrid = 1;
            xlim(app.grafico3D,[-(L1+L2+L3) L1+L2+L3]); 
            zlim(app.grafico3D,[-(L2+L3) L2+L3]); 
            ylim(app.grafico3D,[-(L1+L2+L3) L1+L2+L3]);
            app.grafico3D.BackgroundColor = [0.98 0.71 0.71];
            
%             %CÁLCULO DE LA POSICIÓN FINAL DE LA PATA, EN BASE DE LAS
%             %MATRICES DE TRANSFORMACIÓN
%             xF = -L3*(cosd(q2)*sind(q1)*cosd(q3)-sind(q1)*sind(q2)*sind(q3))+L2*sind(q1)*cosd(q2);
%             yF = L1+L3*(cosd(q2)*sind(q3)+cosd(q3)*sind(q2))-L2*sind(q2);
%             zF = -L3*(cosd(q1)*cosd(q2)*cosd(q3)-cosd(q1)*sind(q2)*sind(q3))+L2*cosd(q1)*cosd(q2);
%             
%             %GRÁFICO DEL PUNTO FINAL DEL MECANISMO
%             app.grafico3D.NextPlot = 'add';
%             plot3(app.grafico3D,xF,-zF,yF,'r*');
            
        end

        % Callback function
        function q1SliderValueChanging(app, event)

            
        end

        % Value changed function: q1Slider
        function q1SliderValueChanged(app, event)
            global vista1
            global vista2
            
            [vista1, vista2] = view(app.grafico3D);
            
            %COLOCAR IMÁGEN DEL SPIDER BOT REFERENCIANDO LAS VARIABLES
            [ruta, nombre, extension] = fileparts(mfilename('fullpath'));
            rutaCompleta = strcat(ruta,'\imagenes\pataSpiderBot.png');
            app.foto.ImageSource = rutaCompleta;
            
            %OBTENER EL TAMAÑO DE LOS ESLABONES DEL SPIDERBOT Y LOS ÁNGULOS ACTUALES
            L1 = app.L1EditField.Value;
            L2 = app.L2EditField.Value;
            L3 = app.L3EditField.Value;
            q1 = app.q1Slider.Value;
            q2 = app.q2Slider.Value;
            q3 = app.q3Slider.Value;
            
            %POSICIONES DE LAS ARTICULACIONES EN FUNCIÓN DEL TAMAÑO DE ESLABONES Y ÁNGULOS DEL MISMO
            L1x = L1*sind(q1);
            L2x = L1x+L2*cosd(q2)*sind(q1);
            L3x = L2x-L3*sind(q3)*sind(q1);
            
            L1y = 0;
            L2y = -L2*sind(q2);
            L3y = L2y - L3*cosd(q3);
            
            L1z = L1*cosd(q1);
            L2z = L1z + L2*cosd(q2)*cosd(q1);
            L3z = L2z - L3*sind(q3)*cosd(q1);
            
            %SE CREAN VECTORES QUE GUARDEN LAS PISICIONES, PARA POSTERIORMENTE GRAFICARLOS
            x = [0, L1x, L2x, L3x];
            y = [0, L1y, L2y, L3y];
            z = [0, L1z, L2z, L3z];
            
            %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
            app.grafico3D.NextPlot = 'replace';
            
            plot3(app.grafico3D,x,-z,y);
            app.grafico3D.XGrid = 1;
            app.grafico3D.YGrid = 1;
            app.grafico3D.ZGrid = 1;
            xlim(app.grafico3D,[-(L1+L2+L3) L1+L2+L3]); 
            zlim(app.grafico3D,[-(L2+L3) L2+L3]); 
            ylim(app.grafico3D,[-(L1+L2+L3) L1+L2+L3]);
            view(app.grafico3D,vista1,vista2);
            app.grafico3D.BackgroundColor = [0.98 0.71 0.71];
            
%             %CÁLCULO DE LA POSICIÓN FINAL DE LA PATA, EN BASE DE LAS
%             %MATRICES DE TRANSFORMACIÓN
%             xF = -L3*(cosd(q2)*sind(q1)*cosd(q3)-sind(q1)*sind(q2)*sind(q3))+L2*sind(q1)*cosd(q2);
%             yF = L1+L3*(cosd(q2)*sind(q3)+cosd(q3)*sind(q2))-L2*sind(q2);
%             zF = -L3*(cosd(q1)*cosd(q2)*cosd(q3)-cosd(q1)*sind(q2)*sind(q3))+L2*cosd(q1)*cosd(q2);
%             
%             %GRÁFICO DEL PUNTO FINAL DEL MECANISMO
%             app.grafico3D.NextPlot = 'add';
%             plot3(app.grafico3D,xF,-zF,yF,'r*');
            
        end

        % Value changed function: q2Slider
        function q2SliderValueChanged(app, event)
            global vista1
            global vista2
            
            [vista1, vista2] = view(app.grafico3D);
            
            %COLOCAR IMÁGEN DEL SPIDER BOT REFERENCIANDO LAS VARIABLES
            [ruta, nombre, extension] = fileparts(mfilename('fullpath'));
            rutaCompleta = strcat(ruta,'\imagenes\pataSpiderBot.png');
            app.foto.ImageSource = rutaCompleta;
            
            %OBTENER EL TAMAÑO DE LOS ESLABONES DEL SPIDERBOT Y LOS ÁNGULOS ACTUALES
            L1 = app.L1EditField.Value;
            L2 = app.L2EditField.Value;
            L3 = app.L3EditField.Value;
            q1 = app.q1Slider.Value;
            q2 = app.q2Slider.Value;
            q3 = app.q3Slider.Value;
            
            %POSICIONES DE LAS ARTICULACIONES EN FUNCIÓN DEL TAMAÑO DE ESLABONES Y ÁNGULOS DEL MISMO
            L1x = L1*sind(q1);
            L2x = L1x+L2*cosd(q2)*sind(q1);
            L3x = L2x-L3*sind(q3)*sind(q1);
            
            L1y = 0;
            L2y = -L2*sind(q2);
            L3y = L2y - L3*cosd(q3);
            
            L1z = L1*cosd(q1);
            L2z = L1z + L2*cosd(q2)*cosd(q1);
            L3z = L2z - L3*sind(q3)*cosd(q1);
            
            %SE CREAN VECTORES QUE GUARDEN LAS PISICIONES, PARA POSTERIORMENTE GRAFICARLOS
            x = [0, L1x, L2x, L3x];
            y = [0, L1y, L2y, L3y];
            z = [0, L1z, L2z, L3z];
            
            %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
            app.grafico3D.NextPlot = 'replace';
            
            plot3(app.grafico3D,x,-z,y);
            app.grafico3D.XGrid = 1;
            app.grafico3D.YGrid = 1;
            app.grafico3D.ZGrid = 1;
            xlim(app.grafico3D,[-(L1+L2+L3) L1+L2+L3]); 
            zlim(app.grafico3D,[-(L2+L3) L2+L3]); 
            ylim(app.grafico3D,[-(L1+L2+L3) L1+L2+L3]);
            view(app.grafico3D,vista1,vista2);
            app.grafico3D.BackgroundColor = [0.98 0.71 0.71];
            
%             %CÁLCULO DE LA POSICIÓN FINAL DE LA PATA, EN BASE DE LAS
%             %MATRICES DE TRANSFORMACIÓN
%             xF = -L3*(cosd(q2)*sind(q1)*cosd(q3)-sind(q1)*sind(q2)*sind(q3))+L2*sind(q1);
%             yF = L1+L3*(cosd(q2)*sind(q3)+cosd(q3)*sind(q2));
%             zF = -L3*(cosd(q1)*cosd(q2)*cosd(q3)-cosd(q1)*sind(q2)*sind(q3))+L2*cosd(q1);
%             
%             %GRÁFICO DEL PUNTO FINAL DEL MECANISMO
%             app.grafico3D.NextPlot = 'add';
%             plot3(app.grafico3D,xF,-zF,yF,'r*');
            
        end

        % Value changed function: q3Slider
        function q3SliderValueChanged(app, event)
            global vista1
            global vista2
            
            [vista1, vista2] = view(app.grafico3D);
            
            %COLOCAR IMÁGEN DEL SPIDER BOT REFERENCIANDO LAS VARIABLES
            [ruta, nombre, extension] = fileparts(mfilename('fullpath'));
            rutaCompleta = strcat(ruta,'\imagenes\pataSpiderBot.png');
            app.foto.ImageSource = rutaCompleta;
            
            %OBTENER EL TAMAÑO DE LOS ESLABONES DEL SPIDERBOT Y LOS ÁNGULOS ACTUALES
            L1 = app.L1EditField.Value;
            L2 = app.L2EditField.Value;
            L3 = app.L3EditField.Value;
            q1 = app.q1Slider.Value;
            q2 = app.q2Slider.Value;
            q3 = app.q3Slider.Value;
            
            %POSICIONES DE LAS ARTICULACIONES EN FUNCIÓN DEL TAMAÑO DE ESLABONES Y ÁNGULOS DEL MISMO
            L1x = L1*sind(q1);
            L2x = L1x+L2*cosd(q2)*sind(q1);
            L3x = L2x-L3*sind(q3)*sind(q1);
            
            L1y = 0;
            L2y = -L2*sind(q2);
            L3y = L2y - L3*cosd(q3);
            
            L1z = L1*cosd(q1);
            L2z = L1z + L2*cosd(q2)*cosd(q1);
            L3z = L2z - L3*sind(q3)*cosd(q1);
            
            %SE CREAN VECTORES QUE GUARDEN LAS PISICIONES, PARA POSTERIORMENTE GRAFICARLOS
            x = [0, L1x, L2x, L3x];
            y = [0, L1y, L2y, L3y];
            z = [0, L1z, L2z, L3z];
            
            %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
            app.grafico3D.NextPlot = 'replace';
            
            plot3(app.grafico3D,x,-z,y);
            app.grafico3D.XGrid = 1;
            app.grafico3D.YGrid = 1;
            app.grafico3D.ZGrid = 1;
            xlim(app.grafico3D,[-(L1+L2+L3) L1+L2+L3]); 
            zlim(app.grafico3D,[-(L2+L3) L2+L3]); 
            ylim(app.grafico3D,[-(L1+L2+L3) L1+L2+L3]);
            view(app.grafico3D,vista1,vista2);
            app.grafico3D.BackgroundColor = [0.98 0.71 0.71];
            
%             %CÁLCULO DE LA POSICIÓN FINAL DE LA PATA, EN BASE DE LAS
%             %MATRICES DE TRANSFORMACIÓN
%             xF = -L3*(cosd(q2)*sind(q1)*cosd(q3)-sind(q1)*sind(q2)*sind(q3))+L2*sind(q1);
%             yF = L1+L3*(cosd(q2)*sind(q3)+cosd(q3)*sind(q2));
%             zF = -L3*(cosd(q1)*cosd(q2)*cosd(q3)-cosd(q1)*sind(q2)*sind(q3))+L2*cosd(q1);
%             
%             %GRÁFICO DEL PUNTO FINAL DEL MECANISMO
%             app.grafico3D.NextPlot = 'add';
%             plot3(app.grafico3D,xF,-zF,yF,'r*');
        end

        % Value changed function: L1EditField
        function L1EditFieldValueChanged(app, event)
            global vista1
            global vista2
            
            [vista1, vista2] = view(app.grafico3D);
            
            %COLOCAR IMÁGEN DEL SPIDER BOT REFERENCIANDO LAS VARIABLES
            [ruta, nombre, extension] = fileparts(mfilename('fullpath'));
            rutaCompleta = strcat(ruta,'\imagenes\pataSpiderBot.png');
            app.foto.ImageSource = rutaCompleta;
            
            %OBTENER EL TAMAÑO DE LOS ESLABONES DEL SPIDERBOT Y LOS ÁNGULOS ACTUALES
            L1 = app.L1EditField.Value;
            L2 = app.L2EditField.Value;
            L3 = app.L3EditField.Value;
            q1 = app.q1Slider.Value;
            q2 = app.q2Slider.Value;
            q3 = app.q3Slider.Value;
            
            %POSICIONES DE LAS ARTICULACIONES EN FUNCIÓN DEL TAMAÑO DE ESLABONES Y ÁNGULOS DEL MISMO
            L1x = L1*sind(q1);
            L2x = L1x+L2*cosd(q2)*sind(q1);
            L3x = L2x-L3*sind(q3)*sind(q1);
            
            L1y = 0;
            L2y = -L2*sind(q2);
            L3y = L2y - L3*cosd(q3);
            
            L1z = L1*cosd(q1);
            L2z = L1z + L2*cosd(q2)*cosd(q1);
            L3z = L2z - L3*sind(q3)*cosd(q1);
            
            %SE CREAN VECTORES QUE GUARDEN LAS PISICIONES, PARA POSTERIORMENTE GRAFICARLOS
            x = [0, L1x, L2x, L3x];
            y = [0, L1y, L2y, L3y];
            z = [0, L1z, L2z, L3z];
            
            %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
            app.grafico3D.NextPlot = 'replace';
            
            plot3(app.grafico3D,x,-z,y);
            app.grafico3D.XGrid = 1;
            app.grafico3D.YGrid = 1;
            app.grafico3D.ZGrid = 1;
            xlim(app.grafico3D,[-(L1+L2+L3) L1+L2+L3]); 
            zlim(app.grafico3D,[-(L2+L3) L2+L3]); 
            ylim(app.grafico3D,[-(L1+L2+L3) L1+L2+L3]);
            view(app.grafico3D,vista1,vista2);
            app.grafico3D.BackgroundColor = [0.98 0.71 0.71];
            
%             %CÁLCULO DE LA POSICIÓN FINAL DE LA PATA, EN BASE DE LAS
%             %MATRICES DE TRANSFORMACIÓN
%             xF = -L3*(cosd(q2)*sind(q1)*cosd(q3)-sind(q1)*sind(q2)*sind(q3))+L2*sind(q1);
%             yF = L1+L3*(cosd(q2)*sind(q3)+cosd(q3)*sind(q2));
%             zF = -L3*(cosd(q1)*cosd(q2)*cosd(q3)-cosd(q1)*sind(q2)*sind(q3))+L2*cosd(q1);
%             
%             %GRÁFICO DEL PUNTO FINAL DEL MECANISMO
%             app.grafico3D.NextPlot = 'add';
%             plot3(app.grafico3D,xF,-zF,yF,'r*');
        end

        % Value changed function: L2EditField
        function L2EditFieldValueChanged(app, event)
            global vista1
            global vista2
            
            [vista1, vista2] = view(app.grafico3D);
            
            %COLOCAR IMÁGEN DEL SPIDER BOT REFERENCIANDO LAS VARIABLES
            [ruta, nombre, extension] = fileparts(mfilename('fullpath'));
            rutaCompleta = strcat(ruta,'\imagenes\pataSpiderBot.png');
            app.foto.ImageSource = rutaCompleta;
            
            %OBTENER EL TAMAÑO DE LOS ESLABONES DEL SPIDERBOT Y LOS ÁNGULOS ACTUALES
            L1 = app.L1EditField.Value;
            L2 = app.L2EditField.Value;
            L3 = app.L3EditField.Value;
            q1 = app.q1Slider.Value;
            q2 = app.q2Slider.Value;
            q3 = app.q3Slider.Value;
            
            %POSICIONES DE LAS ARTICULACIONES EN FUNCIÓN DEL TAMAÑO DE ESLABONES Y ÁNGULOS DEL MISMO
            L1x = L1*sind(q1);
            L2x = L1x+L2*cosd(q2)*sind(q1);
            L3x = L2x-L3*sind(q3)*sind(q1);
            
            L1y = 0;
            L2y = -L2*sind(q2);
            L3y = L2y - L3*cosd(q3);
            
            L1z = L1*cosd(q1);
            L2z = L1z + L2*cosd(q2)*cosd(q1);
            L3z = L2z - L3*sind(q3)*cosd(q1);
            
            %SE CREAN VECTORES QUE GUARDEN LAS PISICIONES, PARA POSTERIORMENTE GRAFICARLOS
            x = [0, L1x, L2x, L3x];
            y = [0, L1y, L2y, L3y];
            z = [0, L1z, L2z, L3z];
            
            %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
            app.grafico3D.NextPlot = 'replace';
            
            plot3(app.grafico3D,x,-z,y);
            app.grafico3D.XGrid = 1;
            app.grafico3D.YGrid = 1;
            app.grafico3D.ZGrid = 1;
            xlim(app.grafico3D,[-(L1+L2+L3) L1+L2+L3]); 
            zlim(app.grafico3D,[-(L2+L3) L2+L3]); 
            ylim(app.grafico3D,[-(L1+L2+L3) L1+L2+L3]);
            view(app.grafico3D,vista1,vista2);
            app.grafico3D.BackgroundColor = [0.98 0.71 0.71];
            
%             %CÁLCULO DE LA POSICIÓN FINAL DE LA PATA, EN BASE DE LAS
%             %MATRICES DE TRANSFORMACIÓN
%             xF = -L3*(cosd(q2)*sind(q1)*cosd(q3)-sind(q1)*sind(q2)*sind(q3))+L2*sind(q1);
%             yF = L1+L3*(cosd(q2)*sind(q3)+cosd(q3)*sind(q2));
%             zF = -L3*(cosd(q1)*cosd(q2)*cosd(q3)-cosd(q1)*sind(q2)*sind(q3))+L2*cosd(q1);
%             
%             %GRÁFICO DEL PUNTO FINAL DEL MECANISMO
%             app.grafico3D.NextPlot = 'add';
%             plot3(app.grafico3D,xF,-zF,yF,'r*');
        end

        % Value changed function: L3EditField
        function L3EditFieldValueChanged(app, event)
            global vista1
            global vista2
            
            [vista1, vista2] = view(app.grafico3D);
            
            %COLOCAR IMÁGEN DEL SPIDER BOT REFERENCIANDO LAS VARIABLES
            [ruta, nombre, extension] = fileparts(mfilename('fullpath'));
            rutaCompleta = strcat(ruta,'\imagenes\pataSpiderBot.png');
            app.foto.ImageSource = rutaCompleta;
            
            %OBTENER EL TAMAÑO DE LOS ESLABONES DEL SPIDERBOT Y LOS ÁNGULOS ACTUALES
            L1 = app.L1EditField.Value;
            L2 = app.L2EditField.Value;
            L3 = app.L3EditField.Value;
            q1 = app.q1Slider.Value;
            q2 = app.q2Slider.Value;
            q3 = app.q3Slider.Value;
            
            %POSICIONES DE LAS ARTICULACIONES EN FUNCIÓN DEL TAMAÑO DE ESLABONES Y ÁNGULOS DEL MISMO
            L1x = L1*sind(q1);
            L2x = L1x+L2*cosd(q2)*sind(q1);
            L3x = L2x-L3*sind(q3)*sind(q1);
            
            L1y = 0;
            L2y = -L2*sind(q2);
            L3y = L2y - L3*cosd(q3);
            
            L1z = L1*cosd(q1);
            L2z = L1z + L2*cosd(q2)*cosd(q1);
            L3z = L2z - L3*sind(q3)*cosd(q1);
            
            %SE CREAN VECTORES QUE GUARDEN LAS PISICIONES, PARA POSTERIORMENTE GRAFICARLOS
            x = [0, L1x, L2x, L3x];
            y = [0, L1y, L2y, L3y];
            z = [0, L1z, L2z, L3z];
            
            %GRÁFICA DEL MECANISMO DE LA PATA SPIDERBOT
            app.grafico3D.NextPlot = 'replace';
            
            plot3(app.grafico3D,x,-z,y);
            app.grafico3D.XGrid = 1;
            app.grafico3D.YGrid = 1;
            app.grafico3D.ZGrid = 1;
            xlim(app.grafico3D,[-(L1+L2+L3) L1+L2+L3]); 
            zlim(app.grafico3D,[-(L2+L3) L2+L3]); 
            ylim(app.grafico3D,[-(L1+L2+L3) L1+L2+L3]);
            view(app.grafico3D,vista1,vista2);
            app.grafico3D.BackgroundColor = [0.98 0.71 0.71];
            
%             %CÁLCULO DE LA POSICIÓN FINAL DE LA PATA, EN BASE DE LAS
%             %MATRICES DE TRANSFORMACIÓN
%             xF = -L3*(cosd(q2)*sind(q1)*cosd(q3)-sind(q1)*sind(q2)*sind(q3))+L2*sind(q1);
%             yF = L1+L3*(cosd(q2)*sind(q3)+cosd(q3)*sind(q2));
%             zF = -L3*(cosd(q1)*cosd(q2)*cosd(q3)-cosd(q1)*sind(q2)*sind(q3))+L2*cosd(q1);
%             
%             %GRÁFICO DEL PUNTO FINAL DEL MECANISMO
%             app.grafico3D.NextPlot = 'add';
%             plot3(app.grafico3D,xF,-zF,yF,'r*');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SIMULACINSPIDERBOTPanel = uipanel(app.UIFigure);
            app.SIMULACINSPIDERBOTPanel.Title = 'SIMULACIÓN SPIDER-BOT';
            app.grafico3D = uiaxes(app.SIMULACINSPIDERBOTPanel);
            title(app.grafico3D, 'Posición Pata')
            xlabel(app.grafico3D, 'X')
            ylabel(app.grafico3D, 'Z')
            app.foto = uiimage(app.SIMULACINSPIDERBOTPanel);
            app.q1SliderLabel = uilabel(app.SIMULACINSPIDERBOTPanel);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.SIMULACINSPIDERBOTPanel);
            app.q1Slider.Limits = [0 360];
            app.q1Slider.ValueChangedFcn = createCallbackFcn(app, @q1SliderValueChanged, true);
            app.q2SliderLabel = uilabel(app.SIMULACINSPIDERBOTPanel);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.SIMULACINSPIDERBOTPanel);
            app.q2Slider.Limits = [0 360];
            app.q2Slider.ValueChangedFcn = createCallbackFcn(app, @q2SliderValueChanged, true);
            app.q3SliderLabel = uilabel(app.SIMULACINSPIDERBOTPanel);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.SIMULACINSPIDERBOTPanel);
            app.q3Slider.Limits = [0 360];
            app.q3Slider.ValueChangedFcn = createCallbackFcn(app, @q3SliderValueChanged, true);
            app.L1EditFieldLabel = uilabel(app.SIMULACINSPIDERBOTPanel);
            app.L1EditFieldLabel.Text = 'L1';
            app.L1EditField = uieditfield(app.SIMULACINSPIDERBOTPanel, 'numeric');
            app.L1EditField.ValueChangedFcn = createCallbackFcn(app, @L1EditFieldValueChanged, true);
            app.L1EditField.Value = 8;
            app.L2EditFieldLabel = uilabel(app.SIMULACINSPIDERBOTPanel);
            app.L2EditFieldLabel.Text = 'L2';
            app.L2EditField = uieditfield(app.SIMULACINSPIDERBOTPanel, 'numeric');
            app.L2EditField.ValueChangedFcn = createCallbackFcn(app, @L2EditFieldValueChanged, true);
            app.L2EditField.Value = 10;
            app.L3EditFieldLabel = uilabel(app.SIMULACINSPIDERBOTPanel);
            app.L3EditFieldLabel.Text = 'L3';
            app.L3EditField = uieditfield(app.SIMULACINSPIDERBOTPanel, 'numeric');
            app.L3EditField.ValueChangedFcn = createCallbackFcn(app, @L3EditFieldValueChanged, true);
            app.L3EditField.Value = 10;
            app.cmLabel = uilabel(app.SIMULACINSPIDERBOTPanel);
            app.cmLabel.Text = 'cm';
            app.cmLabel_2 = uilabel(app.SIMULACINSPIDERBOTPanel);
            app.cmLabel_2.Text = 'cm';
            app.cmLabel_3 = uilabel(app.SIMULACINSPIDERBOTPanel);
            app.cmLabel_3.Text = 'cm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = spiderbotApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end